classdef dfInspectRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        wavFilesListBoxLabel     matlab.ui.control.Label
        wavFilesListBox          matlab.ui.control.ListBox
        SelectFilesButton        matlab.ui.control.Button
        FilesPathEditFieldLabel  matlab.ui.control.Label
        FilesPathEditField       matlab.ui.control.EditField
    end


    properties (Access = private)
        filesPath    % Where the wav files are
        wavFileStr   % What the wav files are
        wavFileNames % What the wav files are called
        curWavFileLoc
        curWavFile
        fs
    end


    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            app.filesPath = uigetdir('C:\');
            app.wavFileStr = dir([app.filesPath '\*.wav']);
            app.wavFileNames = {app.wavFileStr.name};
            app.wavFilesListBox.Items = app.wavFileNames;
        end

        % Value changed function: wavFilesListBox
        function drawSelectedWavFileTime(app, event)
            value = app.wavFilesListBox.Value;
            app.curWavFileLoc = fullfile(app.filesPath, value);
            [app.curWavFile, app.fs] = audioread(app.curWavFileLoc);
            trialLen = length(app.curWavFile);
            trialTime = trialLen/app.fs;
            time      = linspace(0, trialTime, trialLen);
            
            app.UIAxes.
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            app.wavFilesListBoxLabel = uilabel(app.UIFigure);
            app.wavFilesListBoxLabel.Text = 'wavFiles';
            app.wavFilesListBox = uilistbox(app.UIFigure);
            app.wavFilesListBox.Items = {};
            app.wavFilesListBox.ValueChangedFcn = createCallbackFcn(app, @drawSelectedWavFileTime, true);
            app.wavFilesListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.FilesPathEditField.Value = 'C:\DATA';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfInspectRawData

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end