classdef dfInspectRawData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        AudioTime                   matlab.ui.control.UIAxes
        MicrophoneDataListBoxLabel  matlab.ui.control.Label
        TrialListBox                matlab.ui.control.ListBox
        SelectFilesButton           matlab.ui.control.Button
        FilesPathEditFieldLabel     matlab.ui.control.Label
        FilesPathEditField          matlab.ui.control.EditField
        curSessLabel                matlab.ui.control.Label
        AudioSpectrum               matlab.ui.control.UIAxes
        UpArrow                     matlab.ui.control.Button
        DnArrow                     matlab.ui.control.Button
        PlayAudioButton             matlab.ui.control.Button
        LateWarning                 matlab.ui.control.Label
        VoiceBreakWarning           matlab.ui.control.Label
        loadingLabel                matlab.ui.control.Label
        minThresholddBLabel         matlab.ui.control.Label
        minThresh                   matlab.ui.control.NumericEditField
        FrequencyBoundsHzLabel      matlab.ui.control.Label
        lwFreqBnField               matlab.ui.control.NumericEditField
        toLabel                     matlab.ui.control.Label
        upFreqBnField               matlab.ui.control.NumericEditField
        LeakageLabel                matlab.ui.control.Label
        leakage                     matlab.ui.control.NumericEditField
        FrequencyResolutionHzLabel  matlab.ui.control.Label
        freqRes                     matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        defaultPath   % Where to start looking for recorded data
        fullFilePath
        AllRecVar     % All variables and data structures saved as part of a recorded run.
        AudapData     % Data set recorded by Audapter
        
        AudapMic
        NIDAQMic
        fs            % Sampling rate of the recording
        fsNi          % Sampling rate of the NIDAQ recording
        curSess       % Which Subject, and run
        numTrial      % number of trials
        trialLen
        trialLenP
        trialNames    % Names of trials
        trialType     % Control (0)/ Perturbed (1)
        expTrig
        qualCh
        
        curTrialName
        curMicData
        curMicDataNi
        curExpTrig
        curqualCh
        curPertVis
        
        spectMinThreshold
        spectFreqResolution
        spectFrequencyLimits
        spectLeakage
    end

    methods (Access = private)
        
        function confirmUseableFile(app)
            if isfile(app.fullFilePath)
                loadAudapFiles(app, 0)
            else
                fprintf('Not a useable file\n')
            end
        end
    
        function loadAudapFiles(app, event)
            
            app.FilesPathEditField.Value = app.fullFilePath;
            
            app.loadingLabel.Text = 'LOADING...';
            load(app.fullFilePath); % Loads the variable 'DRF';
            app.loadingLabel.Text = '';
            
            app.AllRecVar = DRF;
            app.AudapData = DRF.rawData;
            
            app.AudapMic  = [];
            app.NIDAQMic  = [];
            
            app.fs         = DRF.expParam.sRateAnal;
            app.curSess    = DRF.expParam.curSess;
            app.numTrial   = DRF.expParam.numTrial;
            app.trialLen   = 5;
            app.trialLenP  = app.trialLen*app.fs;
            
            if isfield(DRF.expParam, 'sRateQ')
                app.fsNi = DRF.expParam.sRateQ;
            else
                app.fsNi = 8000;
            end
            
            if isfield(DRF.expParam, 'trialType')
                app.trialType = DRF.expParam.trialType;
            else
                app.trialType = zeros(app.numTrial, 1);
            end
            
            if isfield(DRF.expParam, 'trigs')
                app.expTrig = DRF.expParam.trigs(:,:,1); 
            else
                app.expTrig = repmat([0 0], app.numTrial, 1);
            end
            
            app.qualCh     = [];
            app.trialNames = {};
            for ii = 1:app.numTrial
                append = '';
                if app.trialType(ii) == 1
                    append = '***';
                end              
                app.trialNames{ii} = ['Trial ' num2str(ii) append];
                
                app.curMicData = DRF.rawData(ii).signalIn;
                if isfield(DRF.expParam, 'sRateQ')
                    app.curMicDataNi = DRF.DAQin(:, 5, ii);
                else
                    app.curMicDataNi = resample(app.AudapMic{ii}, app.fsNi, app.fs);
                end
                
                app.curExpTrig = app.expTrig(ii, :);
                app.qualCh     = cat(1, app.qualCh, identifyQualityIssues(app));
            end
            
            app.curSessLabel.Text  = app.curSess;
            app.TrialListBox.Items = app.trialNames;
            app.TrialListBox.Value = app.trialNames(1);           
            switchCurAudio(app, event)
        end
        
        function qC = identifyQualityIssues(app)
            
            audioMic   = app.curMicData;
            audioMicDn = resample(audioMic, app.fsNi, app.fs);
            audioMicNi = app.curMicDataNi;
            qC.expTrig    = app.curExpTrig;
            
            qC.timeLag = xCorrTimeLag(app, audioMicNi, audioMicDn, app.fsNi);
            
            % Fix the trigger positions for this raw audio file
            qC.expTrig = qC.expTrig + qC.timeLag;
            
            qC.curPertOnset  = qC.expTrig(1);
            qC.curPertOffset = qC.expTrig(2);
            
            curTrialLen = length(audioMic);
            trialTime   = curTrialLen/app.fs;
            time        = linspace(0, trialTime, curTrialLen);
            
            envCutoff  = 40;
            thresh     = 0.3;
            breakTol   = 0.1;
            [B, A]     = butter(4, envCutoff/(app.fs/2));
            qC.env     = filter(B, A, abs(app.curMicData));
            
            qC.maxEnv        = max(qC.env);
            qC.threshIdx     = find(qC.env > thresh*qC.maxEnv);
            qC.voiceOnsetInd = qC.threshIdx(1);
            qC.voiceOnsetTim = time(qC.voiceOnsetInd);
            
            qC.spchLBt = qC.curPertOnset  - 0.5; qC.spchLB = round(qC.spchLBt*app.fs);
            qC.spchRBt = qC.curPertOffset + 1.0; qC.spchRB = round(qC.spchRBt*app.fs);
            
            qC.fallOffLog = qC.env(qC.spchLB:qC.spchRB) < thresh*qC.maxEnv;
            qC.chk4Break  = sum(qC.fallOffLog) > breakTol*app.fs; % Last longer than break tolerance
            
            qC.lateNote = ''; 
            if qC.spchLB < qC.voiceOnsetInd
                qC.lateNote = 'Subject started late';
            end
            
            qC.breakNote = '';
            if qC.chk4Break == 1
                qC.breakNote = 'Subject had a voice break';
            end          
        end
        
        function timeLag = xCorrTimeLag(app, sig1, sig2, fs)
            % xCorrTimeLag(app, sig1, sig2, fs) calculates the lag between two (seemingly) 
            % identical time based signals. 
            %
            % if timeLag is positive, then sig1 leads sig2. 
            % if timeLag is negative, then sig1 lags sig2.
            
            % Simple crosscorrelation between two signals
            % Finds the largest peak of the result
            [r, lags]    = xcorr(sig1, sig2);
            [~, peakInd] = max(r);
            maxLag       = lags(peakInd);
            timeLag      = maxLag/fs;
            timeLag      = -timeLag;
        end
        
        function [p, f, t] = calcSpectrogram(app)
            audioMic = app.curMicData;
                       
            curTrialLen = length(audioMic);
            trialTime   = curTrialLen/app.fs;
            
            win      = 0.05;
            winP     = win*app.fs;
            pOV      = 50;
            nOV      = pOV*winP;
            tStep    = round(winP*(1-pOV));
            trialWin = round(1:tStep:(curTrialLen-winP));
            numWin   = length(trialWin);
            
            NFFT = 2^nextpow2(winP);
           
            [p,f,t] = pspectrum(audioMic, app.fs, 'spectrogram', ...
                                'MinThreshold',    app.spectMinThreshold, ...
                                'FrequencyResolution', app.spectFreqResolution,...
                                'FrequencyLimits', app.spectFrequencyLimits,...
                                'Leakage',         app.spectLeakage);
        end
        
        function drawCurTrial_Time(app) 
            audioMic = app.curMicData;
            minAudio = min(audioMic);
            maxAudio = max(audioMic);
            
            curTrialLen = length(audioMic);
            trialTime   = curTrialLen/app.fs;
            time        = linspace(0, trialTime, curTrialLen);
            
            qC = app.curqualCh;
            app.LateWarning.Text       = qC.lateNote;
            app.VoiceBreakWarning.Text = qC.breakNote;
            
            pertAx    = [qC.curPertOnset qC.curPertOffset];
            pertAy    = [2 2];
            pertColor = [0 0 1];
            visOption = {'Off', 'On'};
            
            plot(app.AudioTime, time, audioMic, 'k')
            hold(app.AudioTime,'on')
            
            area(app.AudioTime, pertAx, pertAy, -2, 'FaceColor', pertColor, 'EdgeColor', 'none', 'FaceAlpha', 0.25, 'Visible', visOption{app.curPertVis + 1});
            plot(app.AudioTime, time, qC.env, 'y')
            plot(app.AudioTime, [qC.voiceOnsetTim qC.voiceOnsetTim], [-1.1 1.1], 'r--', 'LineWidth', 2)
%             plot(app.AudioTime, [qualCh.voiceOnsetTim qualCh.voiceOnsetTim], [-1.1 1.1], 'r--', 'LineWidth', 2) 
            hold(app.AudioTime, 'off')
            
            app.AudioTime.Title.String   = app.curTrialName;
            app.AudioTime.Title.FontSize = 18;
            app.AudioTime.XLim         = [0 5];
            app.AudioTime.YLim         = [minAudio maxAudio];
        end
        
        function drawCurTrial_Spect(app)

            [p, f, t] = calcSpectrogram(app); 

            surf(app.AudioSpectrum, t, f, 10*log10(p), 'EdgeColor', 'none'); 
            app.AudioSpectrum.XLim     = [0 5];
            app.AudioSpectrum.YLim     = [0 5000];
            app.AudioSpectrum.View     = [0 90];
            app.AudioSpectrum.Colormap = flipud(gray);
        end
        
        function results = setSpectralSettings(app)
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function setDefaultParams(app, varargin)
            app.defaultPath = 'C:\DATA\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';            
            app.FilesPathEditField.Value = app.defaultPath;
            
            app.spectMinThreshold    = -90;
            app.spectFreqResolution  = 50;
            app.spectFrequencyLimits = [0 5000];
            app.spectLeakage         = 0.5;
            app.minThresh.Value     = app.spectMinThreshold;
            app.freqRes.Value       = app.spectFreqResolution;
            app.lwFreqBnField.Value = app.spectFrequencyLimits(1);
            app.upFreqBnField.Value = app.spectFrequencyLimits(2);
            app.leakage.Value       = app.spectLeakage;
            
            
            if ~isempty(varargin)
                fullPath = varargin{1};
                if exist(fullPath, 'file') == 0
                    fprintf('No file at the path specified!\n')
                    close(app.UIFigure)
                else
                    app.fullFilePath = fullPath;                   
                    confirmUseableFile(app)
                end
            else
                app.fullFilePath = 'W:\Experiments\Dissociating-Role-of-Feedback-in-Voice-Motor-Control\DRF_MN16\SF1\DRF_MN16SF1DRF.mat';
                confirmUseableFile(app)
            end
        end

        % Button pushed function: SelectFilesButton
        function SelectFiles(app, event)
            [fileName, filePath] = uigetfile(app.defaultPath);
            
            if fileName ~= 0
                app.fullFilePath = fullfile(filePath, fileName);       
                confirmUseableFile(app)
            end
        end

        % Value changed function: TrialListBox
        function switchCurAudio(app, event)
            app.curTrialName = app.TrialListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curMicData   = app.AudapData(trialIdx).signalIn;
            app.curqualCh    = app.qualCh(trialIdx);
            app.curPertVis   = app.trialType(trialIdx);       % Visible or not
            
            drawCurTrial_Time(app)
            drawCurTrial_Spect(app)
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDnInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrial
                newtrialIdx = trialIdx + 1;
                app.TrialListBox.Value = app.trialNames(newtrialIdx);
                switchCurAudio(app, event)
            end            
        end

        % Button pushed function: PlayAudioButton
        function PlaySelectedAudio(app, event)
            sound(app.curMicData, app.fs)
        end

        % Callback function: freqRes, leakage, lwFreqBnField, 
        % minThresh, upFreqBnField
        function updateSpectSettings(app, event)
            
            app.spectMinThreshold       = app.minThresh.Value;
            app.spectFreqResolution     = app.freqRes.Value;
            app.spectFrequencyLimits(1) = app.lwFreqBnField.Value;
            app.spectFrequencyLimits(2) = app.upFreqBnField.Value;
            app.spectLeakage            = app.leakage.Value;
            
            drawCurTrial_Spect(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AudioTime = uiaxes(app.UIFigure);
            title(app.AudioTime, ' ')
            xlabel(app.AudioTime, 'Time (s)')
            app.MicrophoneDataListBoxLabel = uilabel(app.UIFigure);
            app.MicrophoneDataListBoxLabel.Text = 'Microphone Data';
            app.TrialListBox = uilistbox(app.UIFigure);
            app.TrialListBox.Items = {};
            app.TrialListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurAudio, true);
            app.TrialListBox.Value = {};
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiles, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.FilesPathEditFieldLabel = uilabel(app.UIFigure);
            app.FilesPathEditFieldLabel.Text = 'Files Path:';
            app.FilesPathEditField = uieditfield(app.UIFigure, 'text');
            app.curSessLabel = uilabel(app.UIFigure);
            app.curSessLabel.Text = '';
            app.AudioSpectrum = uiaxes(app.UIFigure);
            title(app.AudioSpectrum, ' ')
            xlabel(app.AudioSpectrum, 'Time (s)')
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDnInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySelectedAudio, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
            app.LateWarning = uilabel(app.UIFigure);
            app.LateWarning.Text = '';
            app.VoiceBreakWarning = uilabel(app.UIFigure);
            app.VoiceBreakWarning.Text = '';
            app.loadingLabel = uilabel(app.UIFigure);
            app.loadingLabel.Text = '';
            app.minThresholddBLabel = uilabel(app.UIFigure);
            app.minThresholddBLabel.Text = 'min Threshold (dB)';
            app.minThresh = uieditfield(app.UIFigure, 'numeric');
            app.minThresh.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.FrequencyBoundsHzLabel = uilabel(app.UIFigure);
            app.FrequencyBoundsHzLabel.Text = 'Frequency Bounds (Hz)';
            app.lwFreqBnField = uieditfield(app.UIFigure, 'numeric');
            app.lwFreqBnField.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.toLabel = uilabel(app.UIFigure);
            app.toLabel.Text = 'to';
            app.upFreqBnField = uieditfield(app.UIFigure, 'numeric');
            app.upFreqBnField.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.LeakageLabel = uilabel(app.UIFigure);
            app.LeakageLabel.Text = 'Leakage';
            app.leakage = uieditfield(app.UIFigure, 'numeric');
            app.leakage.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
            app.FrequencyResolutionHzLabel = uilabel(app.UIFigure);
            app.FrequencyResolutionHzLabel.Text = 'Frequency Resolution (Hz)';
            app.freqRes = uieditfield(app.UIFigure, 'numeric');
            app.freqRes.ValueChangedFcn = createCallbackFcn(app, @updateSpectSettings, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfInspectRawData(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)setDefaultParams(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end