classdef dfParseRawVideo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        rawVideoView        matlab.ui.control.UIAxes
        TrialsListBoxLabel  matlab.ui.control.Label
        TrialsListBox       matlab.ui.control.ListBox
        UpArrow             matlab.ui.control.Button
        DnArrow             matlab.ui.control.Button
        PlayAudioButton     matlab.ui.control.Button
    end


    properties (Access = private)
        appWinSize
        
        subj
        run
        savedRawDataPath % Description
        savedRawSubjDataPath
        savedRawSubjEndoFiles
        savedRawSubjEndoAudio
        savedRawSubjEndoVideo
        expResultsFile
        
        sRateExp
        allIdxFin
        numTrials
        expAudioM
        trialLenP
        setA
        timef0
        expAudioMf0
        
        trialNames
        curTrialName
        
        vidObj
        vidStr
        
        vidWidth
        vidHeight
        vidFrameR
        
        trialLenF
        setF
        
        nFrames
        curFrame
        
        endoAudio
        endoAfs
        
        endoAudioDN
        trialDelays
        trialRecStTimes
        videoFrameSeg
        videoSeg
        curVidSeg
        endoAudioM
        
        parseTrialT
    end

    methods (Access = private)
    
        function checkFile(app)
            
            if isfile(app.expResultsFile)
                disp('Loading Exp Results...')
                loadExpResults(app)
            else
                error('Cannot Find the Exp Results')
            end
            
            if isfile(app.savedRawSubjEndoVideo)
                disp('Loading Raw Video...')
                loadRawVideo(app)
                loadRawAudio(app)
            else
                error('Not a raw video file')
            end
            
            parseTrials(app)
        end
        
        function loadExpResults(app)
            load(app.expResultsFile) % Returns res
            
            app.sRateExp    = res.sRate;
            app.allIdxFin   = res.allIdxFin;
            app.numTrials   = length(app.allIdxFin);
            app.expAudioM   = res.audioMinc;
            app.trialLenP   = length(app.expAudioM);
            app.setA        = (1:app.trialLenP) - 1;
            app.timef0      = res.timef0;
            app.expAudioMf0 = res.audioMf0TrialPert;
            
            app.trialNames = {};
            for ii = 1:app.numTrials
                app.trialNames{ii} = ['Trial ' num2str(app.allIdxFin(ii))];
            end
            
            app.TrialsListBox.Items = app.trialNames;
            app.TrialsListBox.Value = app.trialNames(1);
        end
        
        function loadRawVideo(app)
            app.vidObj = VideoReader(app.savedRawSubjEndoVideo);
            
            app.vidWidth  = app.vidObj.Width;
            app.vidHeight = app.vidObj.Height;
            app.vidFrameR = app.vidObj.FrameRate;
            app.trialLenF = round(app.trialLenP*(app.vidFrameR/app.sRateExp));
            app.setF      = (1:app.trialLenF) - 1;
            
            app.vidStr = struct('cdata', zeros(app.vidHeight, app.vidWidth, 3, 'uint8'), 'colormap', []);
            
            app.nFrames = 0;
            while hasFrame(app.vidObj)
                app.nFrames = app.nFrames+1;
                app.vidStr(app.nFrames).cdata = readFrame(app.vidObj);                
            end
            
            setViewWindowParams(app)            
        end
        
        function loadRawAudio(app)
            [app.endoAudio, app.endoAfs] = audioread(app.savedRawSubjEndoAudio);
        end
        
        function parseTrials(app)
            
            app.endoAudioDN = resample(app.endoAudio(:,1), app.sRateExp, app.endoAfs);

            app.trialDelays     = zeros(app.numTrials, 1);
            app.trialRecStTimes = zeros(app.numTrials, 1);
            app.videoFrameSeg   = zeros(app.numTrials, 2);
            app.videoSeg        = cell(app.numTrials, 1);
            app.endoAudioM      = zeros(app.trialLenP, app.numTrials);
            for ii = 1:app.numTrials
                app.trialDelays(ii)     = xCorrTimeLag(app, app.endoAudioDN, app.expAudioM(:,ii));
                app.trialRecStTimes(ii) = round(app.trialDelays(ii)/app.sRateExp, 2);
                app.endoAudioM(:,ii)    = app.endoAudioDN(app.setA + app.trialDelays(ii));
                
                videoFrameSt = floor(app.trialRecStTimes(ii)*app.vidFrameR);
                videoFrameSp = videoFrameSt + app.trialLenF;
                
                app.videoFrameSeg(ii, :) = [videoFrameSt videoFrameSp];                
                app.videoSeg{ii} = app.vidStr(videoFrameSt:videoFrameSp);
            end
            app.parseTrialT = table(app.trialRecStTimes, app.videoFrameSeg(:, 1), 'VariableNames',{'Time', 'Frame'});
            
            switchCurTrial(app, 0)
        end
        
        function pointLag = xCorrTimeLag(app, sig1, sig2)
            % xCorrTimeLag(sig1, sig2, fs) calculates the lag between two (seemingly) 
            % identical time based signals. 
            %
            % if timeLag is positive, then sig1 leads sig2. 
            % if timeLag is negative, then sig1 lags sig2.
            
            % Simple crosscorrelation between two signals
            % Finds the largest peak of the result
            [r, lags]    = xcorr(sig1, sig2);
            [~, peakInd] = max(r);
            pointLag     = lags(peakInd);
        end
        
        function dispFrame(app)
            image(app.rawVideoView, app.curVidSeg(1).cdata)
        end
        
        function setViewWindowParams(app)
            
            figW = app.vidWidth+10;
            figH = app.vidHeight+10;
            
            figX = app.appWinSize(1)-figW - 10;
            figY = app.appWinSize(2)-figH - 10;
            
            app.rawVideoView.Position = [figX figY figW figH];

            title(app.rawVideoView, []);
            xlabel(app.rawVideoView, []);
            ylabel(app.rawVideoView, []);
            app.rawVideoView.XAxis.TickLabels = {};
            app.rawVideoView.YAxis.TickLabels = {};
            
            app.rawVideoView.XLim = [0 app.vidWidth];
            app.rawVideoView.YLim = [0 app.vidHeight];            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function initParams(app)
            matlab.video.read.UseHardwareAcceleration('off')
             
            app.appWinSize = app.UIFigure.Position(3:4);
            
            app.subj = 'DRF12';
            app.run  = 'SFL1';
            app.savedRawDataPath     = 'S:\SteppLab2\SPEECHDATA\Voice\Dissociating-Role-of-Feedback-in-Voice-Motor-Control';
            app.savedRawSubjDataPath = fullfile(app.savedRawDataPath, app.subj);
            
            app.savedRawSubjEndoFiles = fullfile(app.savedRawSubjDataPath, 'rawVideo');
            app.savedRawSubjEndoAudio = fullfile(app.savedRawSubjEndoFiles, [app.subj ' rawAudio.wav']);
            app.savedRawSubjEndoVideo = fullfile(app.savedRawSubjEndoFiles, [app.subj ' rawVideo.avi']);
            
            resultsDir = 'C:\Users\djsmith\Documents\MATLAB\dfResults\Results';
            app.expResultsFile = fullfile(resultsDir, app.subj, app.run, [app.subj app.run 'AllResultsDRF.mat']);
            
            checkFile(app)
        end

        % Button pushed function: UpArrow
        function ScrollUpInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= 1
                newtrialIdx = trialIdx - 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Button pushed function: DnArrow
        function ScrollDownInList(app, event)
            trialIdx = find(strcmp(app.trialNames, app.curTrialName) == 1);
            
            if trialIdx ~= app.numTrials
                newtrialIdx = trialIdx + 1;
                app.TrialsListBox.Value = app.trialNames(newtrialIdx);
                switchCurTrial(app, event)
            end
        end

        % Value changed function: TrialsListBox
        function switchCurTrial(app, event)
            app.curTrialName = app.TrialsListBox.Value;
            trialIdx         = strcmp(app.trialNames, app.curTrialName);
            
            app.curVidSeg = app.videoSeg{trialIdx};
    
            dispFrame(app)
        end

        % Button pushed function: PlayAudioButton
        function PlayVideo(app, event)
            curFrames = length(app.curVidSeg);
            numFrameSecs = 1/30;
            
            for ii = 1:curFrames
                
                image(app.rawVideoView, app.curVidSeg(ii).cdata)
                pause(numFrameSecs)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.rawVideoView = uiaxes(app.UIFigure);
            app.rawVideoView.XTickLabel = {''; ''};
            app.rawVideoView.YTickLabel = '';
            app.TrialsListBoxLabel = uilabel(app.UIFigure);
            app.TrialsListBoxLabel.Text = 'Trials';
            app.TrialsListBox = uilistbox(app.UIFigure);
            app.TrialsListBox.ValueChangedFcn = createCallbackFcn(app, @switchCurTrial, true);
            app.UpArrow = uibutton(app.UIFigure, 'push');
            app.UpArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollUpInList, true);
            app.UpArrow.Icon = 'UpArrow.png';
            app.UpArrow.Text = '';
            app.DnArrow = uibutton(app.UIFigure, 'push');
            app.DnArrow.ButtonPushedFcn = createCallbackFcn(app, @ScrollDownInList, true);
            app.DnArrow.Icon = 'DnArrow.png';
            app.DnArrow.Text = '';
            app.PlayAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideo, true);
            app.PlayAudioButton.Icon = 'PlayBtn.png';
            app.PlayAudioButton.Text = 'Play Audio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dfParseRawVideo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @initParams)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end